classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KetQuaDuDoanLabel             matlab.ui.control.Label
        TinhVaVeButton                matlab.ui.control.Button
        KetQuaA1Label                 matlab.ui.control.Label
        KetQuaA0Label                 matlab.ui.control.Label
        A0Label                       matlab.ui.control.Label
        A1Label                       matlab.ui.control.Label
        NhapMangYEditField            matlab.ui.control.EditField
        NhpdliuxLabel_2               matlab.ui.control.Label
        NhapGiaTriCanDuDoanEditField  matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        NhapMangXEditField            matlab.ui.control.EditField
        NhpdliuxLabel                 matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TinhVaVeButton
        function TinhVaVeButtonPushed(app, event)
            mangX = str2num(app.NhapMangXEditField.Value);
            mangY = str2num(app.NhapMangYEditField.Value);
            giaTriCanDuDoan = app.NhapGiaTriCanDuDoanEditField.Value;
            [a0, a1, r2, ketQuaDuDoan] = HoiQuiGiaoDien(mangX, mangY, giaTriCanDuDoan)
            app.KetQuaA0Label.Text = num2str(a0);
            app.KetQuaA1Label.Text = num2str(a1);
            app.KetQuaDuDoanLabel.Text = num2str(ketQuaDuDoan);

            mangGiaTriCanDuDoan = str2num(app.NhapMangXEditField.Value);
            [a0, a1, r2, mangKetQuaDuDoan] = HoiQuiGiaoDien(mangX, mangY, mangGiaTriCanDuDoan)
            plot(app.UIAxes, mangX, mangY, 'ro', mangGiaTriCanDuDoan, mangKetQuaDuDoan, 'b');
            app.TinhVaVeButton.BackgroundColor = [1 0 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpdliuxLabel = uilabel(app.UIFigure);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.NhapMangXEditField = uieditfield(app.UIFigure, 'text');
            app.NhapMangXEditField.Value = '[1 2 3 4 5 6 7 8 9]';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhapGiaTriCanDuDoanEditField = uieditfield(app.UIFigure, 'numeric');
            app.NhapGiaTriCanDuDoanEditField.Value = 12;
            app.NhpdliuxLabel_2 = uilabel(app.UIFigure);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu y';
            app.NhapMangYEditField = uieditfield(app.UIFigure, 'text');
            app.NhapMangYEditField.Value = '[1 1.5 2 3 4 5 8 10 13]';
            app.A1Label = uilabel(app.UIFigure);
            app.A1Label.Text = 'A1';
            app.A0Label = uilabel(app.UIFigure);
            app.A0Label.Text = 'A0';
            app.KetQuaA0Label = uilabel(app.UIFigure);
            app.KetQuaA0Label.Text = '00';
            app.KetQuaA1Label = uilabel(app.UIFigure);
            app.KetQuaA1Label.Text = '00';
            app.TinhVaVeButton = uibutton(app.UIFigure, 'push');
            app.TinhVaVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhVaVeButtonPushed, true);
            app.TinhVaVeButton.Text = 'Tính và Vẽ';
            app.KetQuaDuDoanLabel = uilabel(app.UIFigure);
            app.KetQuaDuDoanLabel.Text = 'Kết quả dự đoán';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end